classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        ResetButton        matlab.ui.control.Button
        ContinueButton     matlab.ui.control.Button
        TeachRobotsButton  matlab.ui.control.Button
        ESTOPButton        matlab.ui.control.Button
        Button_3           matlab.ui.control.Button
        Button_4           matlab.ui.control.Button
        Button_2           matlab.ui.control.Button
        Button             matlab.ui.control.Button
        Image              matlab.ui.control.Image
    end

    
    properties (Access = public)
        VB = false;
        Corona = false;
        Guinness = false;
        Draught = false;

        teach = false;
        reset = false;
        EStopPressed = false;       % Variable to store if the estop has been hit or not
        continuePressed = false;    % Second touch to resume program.
       
        Assignment;
    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Assignment = Assignment2Group();
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            if app.VB == true
                app.VB = false;
            else
                app.VB = true;
            end
            display("VB Selected");
            % Pass drink selection to main
            app.Assignment.CollectOrderFromCustomer("VB")
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            if app.Corona == true
                app.Corona = false;
            else
                app.Corona = true;
            end
            display("Corona Selected");
            % Pass drink selection to main          
            app.Assignment.CollectOrderFromCustomer("Corona")
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            if app.Guinness == true
                app.Guinness = false;
            else
                app.Guinness = true;
            end            
            display("Guinness Selected");
            % Pass drink selection to main
            app.Assignment.CollectOrderFromCustomer("Guinness")
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            if app.Draught == true
                app.Draught = false;
            else
                app.Draught = true;
            end
            display("Draught Selected");
            % Pass drink selection to main
            app.Assignment.CollectOrderFromCustomer("Draught")
        end

        % Button pushed function: ESTOPButton
        function ESTOPButtonPushed(app, event)
            app.EStopPressed = ~app.EStopPressed;
            
            % if e-stop has been pressed on, start/continue must turn false
            if app.EStopPressed
                % Setting continue button to false (for two step starting)
                app.continuePressed = false;
                app.Assignment.eStopPressed = app.EStopPressed;
                display("EStop Engaged");
            else
                % Continue button can be pressed now (e-stop off)
                app.continuePressed = false;
                % app.Assignment.eStopPressed = app.EStopPressed;
                display("Estop disengaged Press Continue when Ready");
            end
        end

        % Button pushed function: TeachRobotsButton
        function TeachRobotsButtonPushed(app, event)
            if app.teach == true
                app.teach = false;
            else
                app.teach = true;
            end
            display("Teach mode engaged");
            %Initiate teach GUI
            teachGUI();
        end

        % Button pushed function: ContinueButton
        function ContinueButtonPushed(app, event)
            % Checking if the e-stop is off (meaning robot can start/continue)
            if app.continuePressed == true
                app.continuePressed = false;
            else
                app.continuePressed = true;
            end

            if ~app.EStopPressed
                app.Assignment.eStopPressed = app.EStopPressed;
                display("Robot Disengaged. Function Continued");
            else
                % Logging that e-stop is on and therefore robots cannot operate
                display("EStop Engaged");
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            if app.teach == true
                app.teach = false;
            else
                app.teach = true;
            end
            display("System Reset");
            clc;
            clf;
            clear all; 
            hold on
            % close all; %Dont close all. Dock the figure for ease of use
            display("ClearAndClose complete");
            app.Assignment = Assignment2Group();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Background.png');
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = fullfile(pathToMLAPP, 'VictoriaBitter.png');
            app.Button.Text = '';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = fullfile(pathToMLAPP, 'Corona.jpg');
            app.Button_2.Text = '';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Icon = fullfile(pathToMLAPP, 'Guinness.png');
            app.Button_4.Text = '';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Icon = fullfile(pathToMLAPP, 'Calton.png');
            app.Button_3.Text = '';
            app.ESTOPButton = uibutton(app.UIFigure, 'push');
            app.ESTOPButton.ButtonPushedFcn = createCallbackFcn(app, @ESTOPButtonPushed, true);
            app.ESTOPButton.Text = 'E-STOP';
            app.TeachRobotsButton = uibutton(app.UIFigure, 'push');
            app.TeachRobotsButton.ButtonPushedFcn = createCallbackFcn(app, @TeachRobotsButtonPushed, true);
            app.TeachRobotsButton.Text = 'Teach Robots';
            app.ContinueButton = uibutton(app.UIFigure, 'push');
            app.ContinueButton.ButtonPushedFcn = createCallbackFcn(app, @ContinueButtonPushed, true);
            app.ContinueButton.Text = 'Continue';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end