classdef LinearLite6GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        q7Button_2               matlab.ui.control.Button
        q7Button                 matlab.ui.control.Button
        TextArea2_7              matlab.ui.control.TextArea
        ESTOPButton              matlab.ui.control.Button
        CloseButton              matlab.ui.control.Button
        LinearLite6ControlLabel  matlab.ui.control.Label
        q5Button_2               matlab.ui.control.Button
        q6Button_2               matlab.ui.control.Button
        q4Button_2               matlab.ui.control.Button
        q3Button_2               matlab.ui.control.Button
        q2Button_2               matlab.ui.control.Button
        q1Button_7               matlab.ui.control.Button
        q5Button                 matlab.ui.control.Button
        q6Button                 matlab.ui.control.Button
        q4Button                 matlab.ui.control.Button
        q3Button                 matlab.ui.control.Button
        q2Button                 matlab.ui.control.Button
        q1Button                 matlab.ui.control.Button
        TextArea2_6              matlab.ui.control.TextArea
        TextArea2_5              matlab.ui.control.TextArea
        TextArea2_4              matlab.ui.control.TextArea
        TextArea2_3              matlab.ui.control.TextArea
        TextArea2_2              matlab.ui.control.TextArea
        TextArea2                matlab.ui.control.TextArea
        YawTextArea              matlab.ui.control.TextArea
        YawTextAreaLabel         matlab.ui.control.Label
        PitchTextArea            matlab.ui.control.TextArea
        PitchLabel               matlab.ui.control.Label
        RollTextArea             matlab.ui.control.TextArea
        RollLabel                matlab.ui.control.Label
        ZTextArea                matlab.ui.control.TextArea
        ZTextAreaLabel           matlab.ui.control.Label
        YTextArea                matlab.ui.control.TextArea
        YTextAreaLabel           matlab.ui.control.Label
        XTextArea                matlab.ui.control.TextArea
        XTextAreaLabel           matlab.ui.control.Label
    end

    
    properties (Access = public)
        Exit = false
        
        q1_b1 = false;
        q2_b1 = false;
        q3_b1 = false;
        q4_b1 = false;
        q5_b1 = false;
        q6_b1 = false;
        q7_b1 = false;

        q1_b2 = false;
        q2_b2 = false;
        q3_b2 = false;
        q4_b2 = false;
        q5_b2 = false;
        q6_b2 = false;
        q7_b2 = false;

        LinearLite6_P;
        LinearLite6_T;
    end
    
    methods (Access = public)
        
        function app = ControlGuiUpdate(app,robot)
            app.LinearLite6_P = robot.model.getpos;
            app.LinearLite6_T = robot.model.fkine(app.LinearLite6_P);
            rpy = rad2deg(tr2rpy(app.LinearLite6_T));

            app.XTextArea.Value = num2str(app.LinearLite6_T(1,4));
            app.YTextArea.Value = num2str(app.LinearLite6_T(2,4));
            app.ZTextArea.Value = num2str(app.LinearLite6_T(3,4));
            app.RollTextArea.Value = num2str(rpy(1));
            app.PitchTextArea.Value = num2str(rpy(2));
            app.YawTextArea.Value = num2str(rpy(3));

            app.TextArea2.Value = num2str(rad2deg(app.LinearLite6_P(1)));
            app.TextArea2_2.Value = num2str(rad2deg(app.LinearLite6_P(2)));
            app.TextArea2_4.Value = num2str(rad2deg(app.LinearLite6_P(3)));
            app.TextArea2_5.Value = num2str(rad2deg(app.LinearLite6_P(4)));
            app.TextArea2_3.Value = num2str(rad2deg(app.LinearLite6_P(5)));
            app.TextArea2_6.Value = num2str(rad2deg(app.LinearLite6_P(6)));
            app.TextArea2_7.Value = num2str(rad2deg(app.LinearLite6_P(7)));

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: q5Button_2
        function q5Button_2Pushed(app, event)
            if app.q5_b2 == true
                app.q5_b2 = false;
            else
                app.q5_b2 = true;
            end
        end

        % Button pushed function: q6Button_2
        function q6Button_2Pushed(app, event)
            if app.q6_b2 == true
                app.q6_b2 = false;
            else
                app.q6_b2 = true;
            end
        end

        % Button pushed function: q4Button_2
        function q4Button_2Pushed(app, event)
            if app.q4_b2 == true
                app.q4_b2 = false;
            else
                app.q4_b2 = true;
            end
        end

        % Button pushed function: q3Button_2
        function q3Button_2Pushed(app, event)
            if app.q3_b2 == true
                app.q3_b2 = false;
            else
                app.q3_b2 = true;
            end
        end

        % Button pushed function: q2Button_2
        function q2Button_2Pushed(app, event)
            if app.q2_b2 == true
                app.q2_b2 = false;
            else
                app.q2_b2 = true;
            end
        end

        % Button pushed function: q1Button_7
        function q1Button_7Pushed(app, event)
            if app.q1_b2 == true
                app.q1_b2 = false;
            else
                app.q1_b2 = true;
            end
        end

        % Button pushed function: q5Button
        function q5ButtonPushed(app, event)
            if app.q5_b1 == true
                app.q5_b1 = false;
            else
                app.q5_b1 = true;
            end
        end

        % Button pushed function: q6Button
        function q6ButtonPushed(app, event)
            if app.q6_b1 == true
                app.q6_b1 = false;
            else
                app.q6_b1 = true;
            end
        end

        % Button pushed function: q4Button
        function q4ButtonPushed(app, event)
            if app.q4_b1 == true
                app.q4_b1 = false;
            else
                app.q4_b1 = true;
            end
        end

        % Button pushed function: q3Button
        function q3ButtonPushed(app, event)
            if app.q3_b1 == true
                app.q3_b1 = false;
            else
                app.q3_b1 = true;
            end
        end

        % Button pushed function: q2Button
        function q2ButtonPushed(app, event)
            if app.q2_b1 == true
                app.q2_b1 = false;
            else
                app.q2_b1 = true;
            end
        end

        % Button pushed function: q1Button
        function q1ButtonPushed(app, event)
            if app.q1_b1 == true
                app.q1_b1 = false;
            else
                app.q1_b1 = true;
            end
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            if app.Exit == true
                app.Exit = false;
            else
                app.Exit = true;
            end
        end

        % Button pushed function: q7Button
        function q7ButtonPushed(app, event)
            if app.q7_b1 == true
                app.q7_b1 = false;
            else
                app.q7_b1 = true;
            end
        end

        % Button pushed function: q7Button_2
        function q7Button_2Pushed(app, event)
            if app.q7_b2 == true
                app.q7_b2 = false;
            else
                app.q7_b2 = true;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.XTextAreaLabel = uilabel(app.UIFigure);
            app.XTextAreaLabel.Text = 'X:';
            app.XTextArea = uitextarea(app.UIFigure);
            app.YTextAreaLabel = uilabel(app.UIFigure);
            app.YTextAreaLabel.Text = 'Y:';
            app.YTextArea = uitextarea(app.UIFigure);
            app.ZTextAreaLabel = uilabel(app.UIFigure);
            app.ZTextAreaLabel.Text = 'Z:';
            app.ZTextArea = uitextarea(app.UIFigure);
            app.RollLabel = uilabel(app.UIFigure);
            app.RollLabel.Text = 'Roll:';
            app.RollTextArea = uitextarea(app.UIFigure);
            app.PitchLabel = uilabel(app.UIFigure);
            app.PitchLabel.Text = 'Pitch:';
            app.PitchTextArea = uitextarea(app.UIFigure);
            app.YawTextAreaLabel = uilabel(app.UIFigure);
            app.YawTextAreaLabel.Text = 'Yaw:';
            app.YawTextArea = uitextarea(app.UIFigure);
            app.TextArea2 = uitextarea(app.UIFigure);
            app.TextArea2_2 = uitextarea(app.UIFigure);
            app.TextArea2_3 = uitextarea(app.UIFigure);
            app.TextArea2_4 = uitextarea(app.UIFigure);
            app.TextArea2_5 = uitextarea(app.UIFigure);
            app.TextArea2_6 = uitextarea(app.UIFigure);
            app.q1Button = uibutton(app.UIFigure, 'push');
            app.q1Button.ButtonPushedFcn = createCallbackFcn(app, @q1ButtonPushed, true);
            app.q1Button.Text = '-q1';
            app.q2Button = uibutton(app.UIFigure, 'push');
            app.q2Button.ButtonPushedFcn = createCallbackFcn(app, @q2ButtonPushed, true);
            app.q2Button.Text = '-q2';
            app.q3Button = uibutton(app.UIFigure, 'push');
            app.q3Button.ButtonPushedFcn = createCallbackFcn(app, @q3ButtonPushed, true);
            app.q3Button.Text = '-q3';
            app.q4Button = uibutton(app.UIFigure, 'push');
            app.q4Button.ButtonPushedFcn = createCallbackFcn(app, @q4ButtonPushed, true);
            app.q4Button.Text = '-q4';
            app.q6Button = uibutton(app.UIFigure, 'push');
            app.q6Button.ButtonPushedFcn = createCallbackFcn(app, @q6ButtonPushed, true);
            app.q6Button.Text = '-q6';
            app.q5Button = uibutton(app.UIFigure, 'push');
            app.q5Button.ButtonPushedFcn = createCallbackFcn(app, @q5ButtonPushed, true);
            app.q5Button.Text = '-q5';
            app.q1Button_7 = uibutton(app.UIFigure, 'push');
            app.q1Button_7.ButtonPushedFcn = createCallbackFcn(app, @q1Button_7Pushed, true);
            app.q1Button_7.Text = '+q1';
            app.q2Button_2 = uibutton(app.UIFigure, 'push');
            app.q2Button_2.ButtonPushedFcn = createCallbackFcn(app, @q2Button_2Pushed, true);
            app.q2Button_2.Text = '+q2';
            app.q3Button_2 = uibutton(app.UIFigure, 'push');
            app.q3Button_2.ButtonPushedFcn = createCallbackFcn(app, @q3Button_2Pushed, true);
            app.q3Button_2.Text = '+q3';
            app.q4Button_2 = uibutton(app.UIFigure, 'push');
            app.q4Button_2.ButtonPushedFcn = createCallbackFcn(app, @q4Button_2Pushed, true);
            app.q4Button_2.Text = '+q4';
            app.q6Button_2 = uibutton(app.UIFigure, 'push');
            app.q6Button_2.ButtonPushedFcn = createCallbackFcn(app, @q6Button_2Pushed, true);
            app.q6Button_2.Text = '+q6';
            app.q5Button_2 = uibutton(app.UIFigure, 'push');
            app.q5Button_2.ButtonPushedFcn = createCallbackFcn(app, @q5Button_2Pushed, true);
            app.q5Button_2.Text = '+q5';
            app.LinearLite6ControlLabel = uilabel(app.UIFigure);
            app.LinearLite6ControlLabel.Text = 'Linear Lite 6 Control';
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.ESTOPButton = uibutton(app.UIFigure, 'push');
            app.ESTOPButton.Text = 'E - STOP';
            app.TextArea2_7 = uitextarea(app.UIFigure);
            app.q7Button = uibutton(app.UIFigure, 'push');
            app.q7Button.ButtonPushedFcn = createCallbackFcn(app, @q7ButtonPushed, true);
            app.q7Button.Text = '-q7';
            app.q7Button_2 = uibutton(app.UIFigure, 'push');
            app.q7Button_2.ButtonPushedFcn = createCallbackFcn(app, @q7Button_2Pushed, true);
            app.q7Button_2.Text = '+q7';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LinearLite6GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end