classdef UR3GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        q5Button_2        matlab.ui.control.Button
        q6Button_2        matlab.ui.control.Button
        q4Button_2        matlab.ui.control.Button
        q3Button_2        matlab.ui.control.Button
        q2Button_2        matlab.ui.control.Button
        q1Button_2        matlab.ui.control.Button
        q5Button          matlab.ui.control.Button
        q6Button          matlab.ui.control.Button
        q4Button          matlab.ui.control.Button
        q3Button          matlab.ui.control.Button
        q2Button          matlab.ui.control.Button
        q1MinusButton     matlab.ui.control.Button
        TextArea2_6       matlab.ui.control.TextArea
        TextArea2_5       matlab.ui.control.TextArea
        TextArea2_4       matlab.ui.control.TextArea
        TextArea2_3       matlab.ui.control.TextArea
        TextArea2_2       matlab.ui.control.TextArea
        TextArea2         matlab.ui.control.TextArea
        CloseButton       matlab.ui.control.Button
        UR3ControlLabel   matlab.ui.control.Label
        YawTextArea       matlab.ui.control.TextArea
        YawTextAreaLabel  matlab.ui.control.Label
        PitchTextArea     matlab.ui.control.TextArea
        PitchLabel        matlab.ui.control.Label
        RollTextArea      matlab.ui.control.TextArea
        RollLabel         matlab.ui.control.Label
        ZTextArea         matlab.ui.control.TextArea
        ZTextAreaLabel    matlab.ui.control.Label
        YTextArea         matlab.ui.control.TextArea
        YTextAreaLabel    matlab.ui.control.Label
        XTextArea         matlab.ui.control.TextArea
        XTextAreaLabel    matlab.ui.control.Label
    end

    
    properties (Access = public)
        Exit = false

        q1Minus= false;

        modelUR3 % Where I will put the loaded puma
        q1 = 0;
        q2 = 0;
        q3 = 0;
        q4 = 0;
        q5 = 0;
        q6 = 0;
        
        qz = [0     0     0     0     0     0];
        qrUr3 = [0	pi/2  -pi/2	0	0	0];
        
        UR3_P;
        UR3_T;
    end
    
    methods (Access = public)
    
        
        function ControlGuiUpdate(app,robot)
            app.UR3_P = [app.q1     app.q2     app.q3     app.q4     app.q5     app.q6];
            app.UR3_T = robot.model.fkineUTS(app.UR3_P);
            rpy = rad2deg(tr2rpy(app.UR3_T));

            app.XTextArea.Value = num2str(app.UR3_T(1,4));
            app.YTextArea.Value = num2str(app.UR3_T(2,4));
            app.ZTextArea.Value = num2str(app.UR3_T(3,4));
            app.RollTextArea.Value = num2str(rpy(1));
            app.PitchTextArea.Value = num2str(rpy(2));
            app.YawTextArea.Value = num2str(rpy(3));

            app.TextArea2.Value = num2str(rad2deg(app.UR3_P(1)));
            app.TextArea2_2.Value = num2str(rad2deg(app.UR3_P(2)));
            app.TextArea2_4.Value = num2str(rad2deg(app.UR3_P(3)));
            app.TextArea2_5.Value = num2str(rad2deg(app.UR3_P(4)));
            app.TextArea2_3.Value = num2str(rad2deg(app.UR3_P(5)));
            app.TextArea2_6.Value = num2str(rad2deg(app.UR3_P(6)));
            
        end
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varagin)
            ax = gca;

            % Make, save and plot some robots            
            mdl_ur3();
            app.modelUR3 = ur3;
            app.qz = [0     0     0     0     0     0];
            plot(app.modelUR3,app.qrUr3,'workspace',[-2 2 -2 2 -0.5 2])            
            hold on;  

            drawnow();

            % Delete the green floor surface (need to count backwards
            % because once it is deleted the array is compressed)
            for i = size(ax.Children,1):-1:1
                if strcmp(ax.Children(i).Type,'surface')
                    delete(ax.Children(i));
                end
            end


        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            if app.Exit == true
                app.Exit = false;
            else
                app.Exit = true;
            end
            clc;
            clf;
            close;
            delete(app.UIFigure)
        end

        % Button pushed function: q1MinusButton
        function q1MinusButtonPushed(app, event)
            if app.q1Minus == true
                app.q1Minus = false;
            else
                app.q1Minus = true;
            end
            app.q1 = app.q1 - 1;

            app.UR3_P = [app.q1     app.q2     app.q3     app.q4     app.q5     app.q6];
            app.UR3_T = ur3.model.fkineUTS(app.UR3_P);
            rpy = rad2deg(tr2rpy(app.UR3_T));

            app.XTextArea.Value = num2str(app.UR3_T(1,4));
            app.YTextArea.Value = num2str(app.UR3_T(2,4));
            app.ZTextArea.Value = num2str(app.UR3_T(3,4));
            app.RollTextArea.Value = num2str(rpy(1));
            app.PitchTextArea.Value = num2str(rpy(2));
            app.YawTextArea.Value = num2str(rpy(3));

            app.TextArea2.Value = num2str(rad2deg(app.UR3_P(1)));
            app.TextArea2_2.Value = num2str(rad2deg(app.UR3_P(2)));
            app.TextArea2_4.Value = num2str(rad2deg(app.UR3_P(3)));
            app.TextArea2_5.Value = num2str(rad2deg(app.UR3_P(4)));
            app.TextArea2_3.Value = num2str(rad2deg(app.UR3_P(5)));
            app.TextArea2_6.Value = num2str(rad2deg(app.UR3_P(6)));

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.XTextAreaLabel = uilabel(app.UIFigure);
            app.XTextAreaLabel.Text = 'X:';
            app.XTextArea = uitextarea(app.UIFigure);
            app.YTextAreaLabel = uilabel(app.UIFigure);
            app.YTextAreaLabel.Text = 'Y:';
            app.YTextArea = uitextarea(app.UIFigure);
            app.ZTextAreaLabel = uilabel(app.UIFigure);
            app.ZTextAreaLabel.Text = 'Z:';
            app.ZTextArea = uitextarea(app.UIFigure);
            app.RollLabel = uilabel(app.UIFigure);
            app.RollLabel.Text = 'Roll:';
            app.RollTextArea = uitextarea(app.UIFigure);
            app.PitchLabel = uilabel(app.UIFigure);
            app.PitchLabel.Text = 'Pitch:';
            app.PitchTextArea = uitextarea(app.UIFigure);
            app.YawTextAreaLabel = uilabel(app.UIFigure);
            app.YawTextAreaLabel.Text = 'Yaw:';
            app.YawTextArea = uitextarea(app.UIFigure);
            app.UR3ControlLabel = uilabel(app.UIFigure);
            app.UR3ControlLabel.Text = 'UR3 Control';
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.TextArea2 = uitextarea(app.UIFigure);
            app.TextArea2_2 = uitextarea(app.UIFigure);
            app.TextArea2_3 = uitextarea(app.UIFigure);
            app.TextArea2_4 = uitextarea(app.UIFigure);
            app.TextArea2_5 = uitextarea(app.UIFigure);
            app.TextArea2_6 = uitextarea(app.UIFigure);
            app.q1MinusButton = uibutton(app.UIFigure, 'push');
            app.q1MinusButton.ButtonPushedFcn = createCallbackFcn(app, @q1MinusButtonPushed, true);
            app.q1MinusButton.Text = '-q1';
            app.q2Button = uibutton(app.UIFigure, 'push');
            app.q2Button.Text = '-q2';
            app.q3Button = uibutton(app.UIFigure, 'push');
            app.q3Button.Text = '-q3';
            app.q4Button = uibutton(app.UIFigure, 'push');
            app.q4Button.Text = '-q4';
            app.q6Button = uibutton(app.UIFigure, 'push');
            app.q6Button.Text = '-q6';
            app.q5Button = uibutton(app.UIFigure, 'push');
            app.q5Button.Text = '-q5';
            app.q1Button_2 = uibutton(app.UIFigure, 'push');
            app.q1Button_2.Text = '+q1';
            app.q2Button_2 = uibutton(app.UIFigure, 'push');
            app.q2Button_2.Text = '+q2';
            app.q3Button_2 = uibutton(app.UIFigure, 'push');
            app.q3Button_2.Text = '+q3';
            app.q4Button_2 = uibutton(app.UIFigure, 'push');
            app.q4Button_2.Text = '+q4';
            app.q6Button_2 = uibutton(app.UIFigure, 'push');
            app.q6Button_2.Text = '+q6';
            app.q5Button_2 = uibutton(app.UIFigure, 'push');
            app.q5Button_2.Text = '+q5';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UR3GUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end