classdef UR3GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Joint7Slider                   matlab.ui.control.Slider
        Joint7SliderLabel              matlab.ui.control.Label
        EditField6_2                   matlab.ui.control.NumericEditField
        RobotDropDown                  matlab.ui.control.DropDown
        RobotDropDownLabel             matlab.ui.control.Label
        Joint6Slider                   matlab.ui.control.Slider
        Joint6SliderLabel              matlab.ui.control.Label
        Joint5Slider                   matlab.ui.control.Slider
        Joint5SliderLabel              matlab.ui.control.Label
        Joint4Slider                   matlab.ui.control.Slider
        Joint4SliderLabel              matlab.ui.control.Label
        Joint3Slider                   matlab.ui.control.Slider
        Joint3SliderLabel              matlab.ui.control.Label
        Joint2Slider                   matlab.ui.control.Slider
        Joint2SliderLabel              matlab.ui.control.Label
        Joint1Slider                   matlab.ui.control.Slider
        Joint1SliderLabel              matlab.ui.control.Label
        ZCoordinateEditField           matlab.ui.control.NumericEditField
        ZCoordinateEditFieldLabel      matlab.ui.control.Label
        YCoordinateEditField           matlab.ui.control.NumericEditField
        YCoordinateEditFieldLabel      matlab.ui.control.Label
        XCoordinateEditField           matlab.ui.control.NumericEditField
        XCoordinateEditFieldLabel      matlab.ui.control.Label
        EditField6                     matlab.ui.control.NumericEditField
        EditField5                     matlab.ui.control.NumericEditField
        EditField4                     matlab.ui.control.NumericEditField
        EditField3                     matlab.ui.control.NumericEditField
        EditField2                     matlab.ui.control.NumericEditField
        EditField1                     matlab.ui.control.NumericEditField
        QueueofCoordinatesSentTextAreaLabel  matlab.ui.control.Label
        SendCartesianCoordinateButton  matlab.ui.control.Button
        CloseButton                    matlab.ui.control.Button
        TeachLabel                     matlab.ui.control.Label
    end

    
    properties (Access = public)
        arduino;                    % Arduino object
        UR3;                        % UR3
        LL6;                        % LinearLite6
        qrUR3 = [0	pi/2  -pi/2	0	0	0];
        qrLL6 = [pi/4	pi/2  -pi/2	0	0	0  0];

        fig;                        % Variable to store the figure to plot within
        robotSelected;              % Variable to store what robot is being manipulated
        
        eStopPressed = false;       % Variable to store if the estop has been hit or not
        eStopReleased = false; 

        Exit = false;

        currentJointAngles;

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varagin)
             ax = gca;

            % Make, save and plot some robots            
            mdl_ur3();
            app.UR3 = ur3;
            app.UR3.base = transl([0,0,0]);
            app.fig = figure(2); % Creating figure to simulate robots
            plot(app.UR3,app.qrUR3,'workspace',[-2 2 -2 2 -0.5 2])            
            hold on;         

            app.LL6 = LinearLite6();
            app.LL6.model.base = app.LL6.model.base *  SE3(0,-0.50,0);
            app.LL6.model.animate(app.qrLL6);
            drawnow(); 

            drawnow();

            % Delete the green floor surface (need to count backwards
            % because once it is deleted the array is compressed)
            for i = size(ax.Children,1):-1:1
                if strcmp(ax.Children(i).Type,'surface')
                    delete(ax.Children(i));
                end
            end
            
            
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            if app.Exit == true
                app.Exit = false;
            else
                app.Exit = true;
            end
            close(app.fig,2); 
            delete(app);
        end

        % Value changing function: Joint1Slider, Joint2Slider, 
        % ...and 5 other components
        function Joint1SliderValueChanging(app, event)
            switch event.Source.Tag
                case '1'
                    app.EditField1.Value = event.Value;
                case '2'
                    app.EditField2.Value = event.Value;
                case '3'
                    app.EditField3.Value = event.Value;
                case '4'
                    app.EditField4.Value = event.Value;
                case '5'
                    app.EditField5.Value = event.Value;
                case '6'
                    app.EditField6.Value = event.Value;
                case '7'
                    app.EditField7.Value = event.Value;  
            end

             % Switch case based off selected robot to manipulate
            switch app.RobotDropDown.Value
                case 'UR3'
                    app.currentJointAngles = app.UR3.getpos();
                    app.currentJointAngles(str2double(event.Source.Tag)) = deg2rad(event.Value);
                    app.UR3.animate(app.currentJointAngles);

                case 'LL6'
                    % Updating the joint angle of specified joint index
                        app.currentJointAngles = app.LL6.getpos();
                        app.currentJointAngles(str2double(event.Source.Tag)) = deg2rad(event.Value);
                        app.LL6.animate(app.currentJointAngles);
            end


        end

        % Value changed function: RobotDropDown
        function RobotDropDownValueChanged(app, event)
        % Enabling/Disabling joint 6 movement depending on if
            % Aubo i5 or Dobot Magician is selected
            switch app.RobotDropDown.Value
                case 'UR3'
                    % Setting current robot property to the Aubo i5
                    app.robotSelected = app.UR3;

                    % Disabling joint 7 movement
                    app.Joint7Slider.Enable = false;

                    % Updating Slider Limits
                    app.Joint1Slider.Limits = [-360 360];
                    app.Joint2Slider.Limits = [-195 0];
                    app.Joint3Slider.Limits = [-150 150];
                    app.Joint4Slider.Limits = [-359 359];
                    app.Joint5Slider.Limits = [-359 359];
                    app.Joint6Slider.Limits = [-5, 5];

                    % Setting slider values to current aubo angles
                    app.currentJointAngles = rad2deg(app.UR3.model.getpos());
                    app.Joint1Slider.Value = app.currentJointAngles(1);
                    app.Joint2Slider.Value = app.currentJointAngles(2);
                    app.Joint3Slider.Value = app.currentJointAngles(3);
                    app.Joint4Slider.Value = app.currentJointAngles(4);
                    app.Joint5Slider.Value = app.currentJointAngles(5);
                    app.Joint6Slider.Value = app.currentJointAngles(6);
                   
                case 'LL6'
                    % Setting current robot property to the dobot magician
                    app.robotSelected = app.LL6;

                    % Enabling joint 7 movement
                    app.Joint7Slider.Enable = true;

                    % Updating Slider Limits
                    app.Joint1Slider.Limits = [0.1, 0.8];
                    app.Joint2Slider.Limits = [-180, 180];
                    app.Joint3Slider.Limits = [-180, 180];
                    app.Joint4Slider.Limits = [-180, 180];
                    app.Joint5Slider.Limits = [-180, 180];
                    app.Joint6Slider.Limits = [-180, 180];
                    app.Joint7Slider.Limits = [-180, 180];

                    % Setting slider values to current aubo angles
                    app.currentJointAngles = rad2deg(app.LL6.model.getpos());
                    app.Joint1Slider.Value = app.currentJointAngles(1);
                    app.Joint2Slider.Value = app.currentJointAngles(2);
                    app.Joint3Slider.Value = app.currentJointAngles(3);
                    app.Joint4Slider.Value = app.currentJointAngles(4);
                    app.Joint5Slider.Value = app.currentJointAngles(5);
                    app.Joint6Slider.Value = app.currentJointAngles(6);
                    app.Joint7Slider.Value = app.currentJointAngles(7);                  
            end
        end

        % Button pushed function: SendCartesianCoordinateButton
        function SendCartesianCoordinateButtonPushed(app, event)
            % Getting the x,y,z values to create a coordiante point
            app.newCoordinate = [app.XCoordinateEditField.Value, ...
                app.YCoordinateEditField.Value, app.ZCoordinateEditField.Value];

            % Switch case based off selected robot to manipulate
            switch app.RobotDropDown.Value
                case 'UR3'
                    % Updating UR3 cartesian goal 
                    %app.UR3.MoveToCartesian(newCoordinate);
                    
                case 'LL6'
                    % Updating LL6 cartesian goal 
                    %app.LL6.MoveToCartesian(newCoordinate);                    
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TeachLabel = uilabel(app.UIFigure);
            app.TeachLabel.Text = 'Teach';
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.SendCartesianCoordinateButton = uibutton(app.UIFigure, 'push');
            app.SendCartesianCoordinateButton.ButtonPushedFcn = createCallbackFcn(app, @SendCartesianCoordinateButtonPushed, true);
            app.SendCartesianCoordinateButton.Text = 'Send Cartesian Coordinate';
            app.QueueofCoordinatesSentTextAreaLabel = uilabel(app.UIFigure);
            app.QueueofCoordinatesSentTextAreaLabel.Text = {'Send End-Effector to '; 'Cartesian Coordinate:'};
            app.EditField1 = uieditfield(app.UIFigure, 'numeric');
            app.EditField2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField3 = uieditfield(app.UIFigure, 'numeric');
            app.EditField4 = uieditfield(app.UIFigure, 'numeric');
            app.EditField5 = uieditfield(app.UIFigure, 'numeric');
            app.EditField6 = uieditfield(app.UIFigure, 'numeric');
            app.XCoordinateEditFieldLabel = uilabel(app.UIFigure);
            app.XCoordinateEditFieldLabel.Text = 'X Coordinate';
            app.XCoordinateEditField = uieditfield(app.UIFigure, 'numeric');
            app.YCoordinateEditFieldLabel = uilabel(app.UIFigure);
            app.YCoordinateEditFieldLabel.Text = 'Y Coordinate';
            app.YCoordinateEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZCoordinateEditFieldLabel = uilabel(app.UIFigure);
            app.ZCoordinateEditFieldLabel.Text = 'Z Coordinate';
            app.ZCoordinateEditField = uieditfield(app.UIFigure, 'numeric');
            app.Joint1SliderLabel = uilabel(app.UIFigure);
            app.Joint1SliderLabel.Text = 'Joint 1';
            app.Joint1Slider = uislider(app.UIFigure);
            app.Joint1Slider.Limits = [-175 175];
            app.Joint1Slider.MajorTicks = [];
            app.Joint1Slider.ValueChangingFcn = createCallbackFcn(app, @Joint1SliderValueChanging, true);
            app.Joint1Slider.MinorTicks = [];
            app.Joint1Slider.Tag = '1';
            app.Joint2SliderLabel = uilabel(app.UIFigure);
            app.Joint2SliderLabel.Text = 'Joint 2';
            app.Joint2Slider = uislider(app.UIFigure);
            app.Joint2Slider.Limits = [-175 175];
            app.Joint2Slider.MajorTicks = [];
            app.Joint2Slider.ValueChangingFcn = createCallbackFcn(app, @Joint1SliderValueChanging, true);
            app.Joint2Slider.MinorTicks = [];
            app.Joint2Slider.Tag = '2';
            app.Joint3SliderLabel = uilabel(app.UIFigure);
            app.Joint3SliderLabel.Text = 'Joint 3';
            app.Joint3Slider = uislider(app.UIFigure);
            app.Joint3Slider.Limits = [-175 175];
            app.Joint3Slider.MajorTicks = [];
            app.Joint3Slider.ValueChangingFcn = createCallbackFcn(app, @Joint1SliderValueChanging, true);
            app.Joint3Slider.MinorTicks = [];
            app.Joint3Slider.Tag = '3';
            app.Joint4SliderLabel = uilabel(app.UIFigure);
            app.Joint4SliderLabel.Text = 'Joint 4';
            app.Joint4Slider = uislider(app.UIFigure);
            app.Joint4Slider.Limits = [-175 175];
            app.Joint4Slider.MajorTicks = [];
            app.Joint4Slider.ValueChangingFcn = createCallbackFcn(app, @Joint1SliderValueChanging, true);
            app.Joint4Slider.MinorTicks = [];
            app.Joint4Slider.Tag = '4';
            app.Joint5SliderLabel = uilabel(app.UIFigure);
            app.Joint5SliderLabel.Text = 'Joint 5';
            app.Joint5Slider = uislider(app.UIFigure);
            app.Joint5Slider.Limits = [-175 175];
            app.Joint5Slider.MajorTicks = [];
            app.Joint5Slider.ValueChangingFcn = createCallbackFcn(app, @Joint1SliderValueChanging, true);
            app.Joint5Slider.MinorTicks = [];
            app.Joint5Slider.Tag = '5';
            app.Joint6SliderLabel = uilabel(app.UIFigure);
            app.Joint6SliderLabel.Text = 'Joint 6';
            app.Joint6Slider = uislider(app.UIFigure);
            app.Joint6Slider.Limits = [-175 175];
            app.Joint6Slider.MajorTicks = [];
            app.Joint6Slider.ValueChangingFcn = createCallbackFcn(app, @Joint1SliderValueChanging, true);
            app.Joint6Slider.MinorTicks = [];
            app.Joint6Slider.Tag = '6';
            app.RobotDropDownLabel = uilabel(app.UIFigure);
            app.RobotDropDownLabel.Text = 'Robot';
            app.RobotDropDown = uidropdown(app.UIFigure);
            app.RobotDropDown.Items = {'UR3', 'LL6'};
            app.RobotDropDown.ValueChangedFcn = createCallbackFcn(app, @RobotDropDownValueChanged, true);
            app.RobotDropDown.Value = 'UR3';
            app.EditField6_2 = uieditfield(app.UIFigure, 'numeric');
            app.Joint7SliderLabel = uilabel(app.UIFigure);
            app.Joint7SliderLabel.Text = 'Joint 7';
            app.Joint7Slider = uislider(app.UIFigure);
            app.Joint7Slider.Limits = [-175 175];
            app.Joint7Slider.MajorTicks = [];
            app.Joint7Slider.ValueChangingFcn = createCallbackFcn(app, @Joint1SliderValueChanging, true);
            app.Joint7Slider.MinorTicks = [];
            app.Joint7Slider.Tag = '6';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UR3GUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end